classdef CV_Zurich_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CapacitanceVoltageCVMFIAZurichTab  matlab.ui.container.Tab
        SaveDataButton                  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        SelectFolderButton              matlab.ui.control.Button
        DefaultFolderEditField          matlab.ui.control.EditField
        DefaultFolderEditFieldLabel     matlab.ui.control.Label
        ConnectionStatusPanel           matlab.ui.container.Panel
        MFIAZurichInstrumentsLamp       matlab.ui.control.Lamp
        MFIAZurichInstrumentsLampLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_sample_info               matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        CommonSettingsPanel             matlab.ui.container.Panel
        UpdateIntervalEditField         matlab.ui.control.NumericEditField
        UpdateIntervalEditFieldLabel    matlab.ui.control.Label
        VoltageaftermeasureSwitch       matlab.ui.control.Switch
        VoltageaftermeasureSwitchLabel  matlab.ui.control.Label
        SourcetoMeasureDelaySEditField  matlab.ui.control.NumericEditField
        SourcetoMeasureDelaySEditFieldLabel  matlab.ui.control.Label
        RepeatEditField                 matlab.ui.control.NumericEditField
        RepeatEditFieldLabel            matlab.ui.control.Label
        SourceSweepPointsEditField      matlab.ui.control.NumericEditField
        SourceSweepPointsLabel          matlab.ui.control.Label
        MeasurementParametersPanel      matlab.ui.container.Panel
        AmpsLabel_2                     matlab.ui.control.Label
        CurrentLimitEditField           matlab.ui.control.NumericEditField
        CurrentLimitEditFieldLabel      matlab.ui.control.Label
        VoltsLabel_7                    matlab.ui.control.Label
        StopVoltageEditField            matlab.ui.control.NumericEditField
        StopVoltageEditFieldLabel       matlab.ui.control.Label
        VoltsLabel_6                    matlab.ui.control.Label
        StartVoltageEditField           matlab.ui.control.NumericEditField
        StartVoltageEditFieldLabel      matlab.ui.control.Label
        CVZurichInstrumentsMFIALabel    matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        ConstantValuesTabel             matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        MeasurementTable                matlab.ui.control.Table
        MeasurementTableLabel           matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        ConstantsPanel                  matlab.ui.container.Panel
        MeasureCountTextArea            matlab.ui.control.TextArea
        MeasureWindowSTextArea          matlab.ui.control.TextArea
        DelaySTextArea                  matlab.ui.control.TextArea
        MeasureCountTextAreaLabel       matlab.ui.control.Label
        MeasurementWindowLabel          matlab.ui.control.Label
        DelaySTextAreaLabel             matlab.ui.control.Label
        CalculatedPointsTextArea        matlab.ui.control.TextArea
        SweepPointsTextArea             matlab.ui.control.TextArea
        StepTextArea                    matlab.ui.control.TextArea
        CalculatedPointsTextArea_2Label  matlab.ui.control.Label
        SweepPointsTextAreaLabel        matlab.ui.control.Label
        CalculatedPointsLabel_2         matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        VoltageLabel_2                  matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        CV_Plot_Linear                  matlab.ui.control.UIAxes
        CV_Plot_Logarithmic             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SampleInfo;
        Comments;
        additionalComments;
        additionalCommentsCell;
        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;

        data;
        sheet1;
        sheet2;
        settings;
        measurement_table;
        measurement_var_names;
        consttableData;
        defaultFolder;
            
        i;
        voltage_source;
        current_measure;

        MFIA;
        connectionStatusMFIA;
        freq;
        Calculatedpoints;
        aftervoltage;
        Step;
   
        StartVoltage;
        StopVoltage;
        Voltagerange;
        Currentlimit;

        Sweeppoints;
        CurrentRange;
        Repeat;
        SourcetoMeasureDelay;
        measurement_window;
        NPLC;
        Measurecount;
        updateInterval;
        stopExperiment;

    end

    methods (Access = public)
        function connectDeviceZurich(app)
            if strcmp(app.connectionStatusMFIA,'Connected')
                delete(app.MFIA)
                clear ziDAQ;
                app.connectionStatusMFIA='Not Connected';
            end
            try
                app.MFIA = ziCreateAPISession('dev5478', 5);
                app.MFIAZurichInstrumentsLamp.Color='g';
                app.connectionStatusMFIA='Connected';
            catch
                app.MFIAZurichInstrumentsLamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('Connection Failed!');
                app.connectionStatusMFIA='Not Connected';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
            defaultFallback = fullfile(userpath,'MyAppData');
            app.DefaultFolderEditField.Value = getpref('MyApp','DataFolder', defaultFallback);
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %% Initialization:
            format longE
            app.defaultFolder = app.DefaultFolderEditField.Value;
            app.connectDeviceZurich();
            ziDisableEverything('dev5478');

            if ~strcmp(app.connectionStatusMFIA,'Connected')
                uialert(app.UIFigure,'Cannot start the experiment because the device is not connected','Connection Error')
                clear;
                return;
            end
            app.stopExperiment = false;
            app.IndicatorLamp.Color='y';
            app.StateoftheExperimentTextArea.Value=('Experiment started: initializing parameters');
            app.StartExperimentButton.Enable="off";

            cla(app.CV_Plot_Linear)
            cla(app.CV_Plot_Logarithmic)
            app.MeasurementTable.Data=[];
            app.ConstantValuesTabel.Data=[];
            
            app.i = 1;
            app.voltage_source=zeros(app.i,1);
            app.current_measure =zeros(app.i,1);

            app.StartVoltage=app.StartVoltageEditField.Value;
            app.StopVoltage=app.StopVoltageEditField.Value;
            app.Currentlimit=app.CurrentLimitEditField.Value;
 
            app.Sweeppoints=app.SourceSweepPointsEditField.Value;
            app.CurrentRange=app.CurrentRangeListBox.Value;
            app.Repeat=app.RepeatEditField.Value;
            app.SourcetoMeasureDelay=app.SourcetoMeasureDelaySEditField.Value;
            app.NPLC=app.NPLCEditField.Value;

            app.Measurecount=app.MeasurecountEditField.Value;
            app.aftervoltage=app.VoltageaftermeasureSwitch.Value;
            app.updateInterval=app.UpdateIntervalEditField.Value;
            
            app.SampleInfo=app.SampleInfoEditField.Value;
            app.Comments=app.CommentsEditField.Value;
            
            %% Parameters:
            app.sheet1 ='Settings';
            app.sheet2 ='Measurements';

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.freq=str2double(writeread(app.MFIA,'print(frequency)'));
            pause(0.1)
            app.measurement_window=app.NPLC/app.freq;
            if strcmp(app.Dualsweep,'On')
            app.Calculatedpoints = (app.Repeat+1) * ((2 * app.Sweeppoints) - (1));
            else
            app.Calculatedpoints = app.Sweeppoints * (app.Repeat+1);
            end
            app.Step = (app.StopVoltage - app.StartVoltage) / (app.Sweeppoints-1);
            app.consttableData=[app.freq, app.NPLC];
            app.ConstantValuesTabel.ColumnFormat={'numeric'};
            app.ConstantValuesTabel.Data=app.consttableData;
            app.StepTextArea.Value=num2str(app.Step);
            app.SweepPointsTextArea.Value=num2str(app.Sweeppoints);
            app.CalculatedPointsTextArea.Value=num2str(app.Calculatedpoints);
            app.DelaySTextArea.Value=num2str(app.SourcetoMeasureDelay);
            app.MeasureWindowSTextArea.Value=num2str(app.measurement_window);
            app.MeasureCountTextArea.Value=num2str(app.Measurecount);
            app.additionalComments = [];

            %% Device Settings:
            writeline(app.MFIA,'*RST')
            pause(0.1)
            %Voltage is the SOURCE function, Current is measurement:
            writeline(app.MFIA,'smu.measure.func = smu.FUNC_DC_CURRENT')
            writeline(app.MFIA,'smu.source.func = smu.FUNC_DC_VOLTAGE')
            writeline(app.MFIA,'frequency=localnode.linefreq')
            %Measure Settings for KEITHLEY:
            writeline(app.MFIA,'smu.measure.terminals = smu.TERMINALS_REAR')
            writeline(app.MFIA,'smu.measure.sense = smu.SENSE_2WIRE')
            writeline(app.MFIA,'smu.measure.autorange = smu.ON')
            writeline(app.MFIA, strcat('smu.measure.count = ',string(app.Measurecount)))
            writeline(app.MFIA, strcat('smu.measure.nplc = ', string(app.NPLC)))
            % Source Settings for KEITHLEY:
            writeline(app.MFIA,'smu.source.highc = smu.OFF')
            writeline(app.MFIA,'smu.source.autorange = smu.ON')
            writeline(app.MFIA,'smu.source.readback = smu.ON')
            writeline(app.MFIA,'smu.source.output = smu.ON')
            writeline(app.MFIA,'smu.source.offmode = smu.OFFMODE_NORMAL')
            writeline(app.MFIA, strcat('smu.source.ilimit.level = ', string(app.Currentlimit)))

            %% Measurements
            writeline(app.MFIA,'beeper.beep(0.35, 1500); delay(0.35) ; beeper.beep(0.35, 1500)')
            app.StateoftheExperimentTextArea.Value=('Measurements have started');
            app.IndicatorLamp.Color='r';
            writeline(app.MFIA,'Voltage_Current_Buffer = buffer.make(1000),buffer.STYLE_WRITABLE_FULL')
            writeline(app.MFIA,'Voltage_Current_Buffer.clear()')

            %--- Repeat Sweep ---
            for r = 1:app.Repeat+1
                %--- Forward Sweep: start -> stop ---
                voltage = app.StartVoltage;
                for k = 1:app.Sweeppoints
                    if app.stopExperiment
                        break;
                    end
                    % Set the source voltage for the current step and read  
                    writeline(app.MFIA, strcat('smu.source.level = ',string(voltage)))
                    pause(app.SourcetoMeasureDelay);
                    writeline(app.MFIA, 'Voltage_Current_Buffer.clear()')
                    writeline(app.MFIA,'smu.measure.read(Voltage_Current_Buffer)')
                    app.voltage_source (app.i,1)=mean(str2double(split(writeread(app.MFIA,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                    app.current_measure(app.i,1)=mean(str2double(split(writeread(app.MFIA,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));       
                   
                    if mod(k, app.updateInterval) == 0 || k == app.Sweeppoints
                        app.StateoftheExperimentTextArea.Value = sprintf('Measurement point number: %d', app.i);
                        app.VoltageTextArea.Value = num2str(app.voltage_source(app.i,1));
                        app.CurrentTextArea.Value = num2str(app.current_measure(app.i,1));
                        plot(app.CV_Plot_Linear, app.voltage_source(1:app.i), app.current_measure(1:app.i));
                        semilogy(app.CV_Plot_Logarithmic, app.voltage_source(1:app.i), abs(app.current_measure(1:app.i)));
                        drawnow;
                    end

                    voltage = voltage + app.Step;
                    app.i = app.i + 1;
                end
                
                %--- Reverse Sweep (if dualSweep is enabled): stop -> start ---
                if strcmp(app.Dualsweep,'On')
                    voltage = app.StopVoltage - app.Step;
                    for k = 1:app.Sweeppoints - 1
                    if app.stopExperiment
                        break;
                    end
                    % Set the source voltage for the current step and read  
                    writeline(app.MFIA, strcat('smu.source.level = ',string(voltage)))
                    pause(app.SourcetoMeasureDelay);
                    writeline(app.MFIA, 'Voltage_Current_Buffer.clear()')
                    writeline(app.MFIA,'smu.measure.read(Voltage_Current_Buffer)')
                    app.voltage_source (app.i,1)=mean(str2double(split(writeread(app.MFIA,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                    app.current_measure(app.i,1)=mean(str2double(split(writeread(app.MFIA,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));       

                    if mod(k, app.updateInterval) == 0 || k == app.Sweeppoints
                        app.StateoftheExperimentTextArea.Value = sprintf('Measurement point number: %d', app.i);
                        app.VoltageTextArea.Value = num2str(app.voltage_source(app.i,1));
                        app.CurrentTextArea.Value = num2str(app.current_measure(app.i,1));
                        plot(app.CV_Plot_Linear, app.voltage_source(1:app.i), app.current_measure(1:app.i));
                        semilogy(app.CV_Plot_Logarithmic, app.voltage_source(1:app.i), abs(app.current_measure(1:app.i)));
                        drawnow;
                    end

                    voltage = voltage - app.Step;
                    app.i = app.i + 1;

                    end
                end
            if app.stopExperiment
                break;
            end
            end
        
            MeasurementtableData = [(1:app.i-1)', app.voltage_source(1:app.i-1), app.current_measure(1:app.i-1)];
            app.MeasurementTable.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.MeasurementTable.Data = MeasurementtableData;

            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end

            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Start Voltage, V', app.StartVoltage ;
            'Stop Voltage, V', app.StopVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Step, V', app.Step ;
            '', '' ;
            'Dual Sweep', app.Dualsweep ;
            'Repeat', app.Repeat ;
            'Sweep Points', app.Sweeppoints ;
            'Calculated Points', app.Calculatedpoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };

            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Voltage', 'Current'};
                app.measurement_table = table(app.voltage_source, app.current_measure ,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end

            app.StartExperimentButton.Enable="on";
    
            %% Finishing the Experiment
            writeline(app.MFIA,'smu.source.output = smu.OFF') %Put output off in KEITHLEY
            if strcmp(app.aftervoltage,'Off')
                writeline(app.MFIA, 'smu.source.level = 0')
            end

            pause(1);
            app.StateoftheExperimentTextArea.Value=('The Experiment is done!');
            app.IndicatorLamp.Color='w';
            app.MFIAZurichInstrumentsLamp.Color='w';
            app.StartExperimentButton.Enable="on";
            clear
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            app.MFIAZurichInstrumentsLamp.Color='w';

            app.StateoftheExperimentTextArea.Value=('The Experiment has been stopped, Save the data!');
            writeline(app.MFIA,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            if strcmp(app.aftervoltage,'Off')
                writeline(app.MFIA, 'smu.source.level = 0')
            end
            app.stopExperiment = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Start Voltage, V', app.StartVoltage ;
            'Stop Voltage, V', app.StopVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Step, V', app.Step ;
            '', '' ;
            'Dual Sweep', app.Dualsweep ;
            'Repeat', app.Repeat ;
            'Sweep Points', app.Sweeppoints ;
            'Calculated Points', app.Calculatedpoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };

            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Voltage', 'Current'};
                app.measurement_table = table(app.voltage_source, app.current_measure ,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
        prevFolder = app.DefaultFolderEditField.Value;
        selected = uigetdir(prevFolder, 'Select Folder');
        if selected ~= 0
            setpref('MyApp','DataFolder', selected);
            app.DefaultFolderEditField.Value = selected;
        end
        figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CapacitanceVoltageCVMFIAZurichTab = uitab(app.TabGroup2);
            app.CapacitanceVoltageCVMFIAZurichTab.Title = 'Capacitance-Voltage (C-V, MFIA Zurich)';
            app.TabGroup = uitabgroup(app.CapacitanceVoltageCVMFIAZurichTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.CVZurichInstrumentsMFIALabel = uilabel(app.SettingsTab);
            app.CVZurichInstrumentsMFIALabel.Text = 'C-V, Zurich Instruments MFIA';
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.StartVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StartVoltageEditFieldLabel.Text = 'Start Voltage';
            app.StartVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StartVoltageEditField.Value = -1;
            app.VoltsLabel_6 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_6.Text = '[Volts]';
            app.StopVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StopVoltageEditFieldLabel.Text = 'Stop Voltage';
            app.StopVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StopVoltageEditField.Value = 1;
            app.VoltsLabel_7 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_7.Text = '[Volts]';
            app.CurrentLimitEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentLimitEditFieldLabel.Text = 'Current Limit';
            app.CurrentLimitEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.CurrentLimitEditField.Value = 0.1;
            app.AmpsLabel_2 = uilabel(app.MeasurementParametersPanel);
            app.AmpsLabel_2.Text = '[Amps]';
            app.CommonSettingsPanel = uipanel(app.SettingsTab);
            app.CommonSettingsPanel.Title = 'Common Settings';
            app.SourceSweepPointsLabel = uilabel(app.CommonSettingsPanel);
            app.SourceSweepPointsLabel.Text = 'Source/ Sweep Points';
            app.SourceSweepPointsEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourceSweepPointsEditField.Limits = [2 Inf];
            app.SourceSweepPointsEditField.Value = 21;
            app.RepeatEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourcetoMeasureDelaySEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.SourcetoMeasureDelaySEditFieldLabel.Text = {'Source to Measure Delay'; '[S]'};
            app.SourcetoMeasureDelaySEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourcetoMeasureDelaySEditField.Value = 0.1;
            app.VoltageaftermeasureSwitchLabel = uilabel(app.CommonSettingsPanel);
            app.VoltageaftermeasureSwitchLabel.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch = uiswitch(app.CommonSettingsPanel, 'slider');
            app.UpdateIntervalEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.UpdateIntervalEditFieldLabel.Text = 'Update Interval';
            app.UpdateIntervalEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.UpdateIntervalEditField.Value = 1;
            app.Panel_sample_info = uipanel(app.SettingsTab);
            app.Panel_sample_info.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_sample_info);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_sample_info, 'text');
            app.SampleInfoEditField.Tooltip = {''};
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.MFIAZurichInstrumentsLampLabel = uilabel(app.ConnectionStatusPanel);
            app.MFIAZurichInstrumentsLampLabel.Text = 'MFIA Zurich Instruments';
            app.MFIAZurichInstrumentsLamp = uilamp(app.ConnectionStatusPanel);
            app.DefaultFolderEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultFolderEditFieldLabel.Text = 'Default Folder';
            app.DefaultFolderEditField = uieditfield(app.SettingsTab, 'text');
            app.SelectFolderButton = uibutton(app.SettingsTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'SelectFolder';
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.MeasurementTableLabel = uilabel(app.Tables);
            app.MeasurementTableLabel.Text = 'Measurement Table';
            app.MeasurementTable = uitable(app.Tables);
            app.MeasurementTable.ColumnName = {'Number'; 'Voltage [V]'; 'Capacitance [F]'};
            app.MeasurementTable.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.ConstantValuesTabel = uitable(app.Tables);
            app.ConstantValuesTabel.ColumnName = {'Frequency'; 'NPLC'};
            app.ConstantValuesTabel.RowName = {};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.CV_Plot_Logarithmic = uiaxes(app.GraphsTab);
            title(app.CV_Plot_Logarithmic, 'Logarithmic C-V, MFIA')
            xlabel(app.CV_Plot_Logarithmic, 'Voltage [V]')
            ylabel(app.CV_Plot_Logarithmic, 'Logarithmic Current [A]')
            zlabel(app.CV_Plot_Logarithmic, 'Z')
            app.CV_Plot_Linear = uiaxes(app.GraphsTab);
            title(app.CV_Plot_Linear, 'Linear C-V, MFIA')
            xlabel(app.CV_Plot_Linear, 'Voltage [V]')
            ylabel(app.CV_Plot_Linear, 'Linear Current [A]')
            zlabel(app.CV_Plot_Linear, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.StateoftheExperimentTextArea.Editable = 'off';
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.VoltageLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Current';
            app.VoltageTextArea = uitextarea(app.MeasurementsPanel);
            app.VoltageTextArea.Editable = 'off';
            app.VoltsLabel = uilabel(app.MeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.CurrentTextArea = uitextarea(app.MeasurementsPanel);
            app.CurrentTextArea.Editable = 'off';
            app.AmpsLabel = uilabel(app.MeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.ConstantsPanel = uipanel(app.GraphsTab);
            app.ConstantsPanel.Title = 'Constants';
            app.CalculatedPointsLabel_2 = uilabel(app.ConstantsPanel);
            app.CalculatedPointsLabel_2.Text = 'Step';
            app.SweepPointsTextAreaLabel = uilabel(app.ConstantsPanel);
            app.SweepPointsTextAreaLabel.Text = 'Sweep Points';
            app.CalculatedPointsTextArea_2Label = uilabel(app.ConstantsPanel);
            app.CalculatedPointsTextArea_2Label.Text = 'Calculated Points';
            app.StepTextArea = uitextarea(app.ConstantsPanel);
            app.StepTextArea.Editable = 'off';
            app.SweepPointsTextArea = uitextarea(app.ConstantsPanel);
            app.SweepPointsTextArea.Editable = 'off';
            app.CalculatedPointsTextArea = uitextarea(app.ConstantsPanel);
            app.CalculatedPointsTextArea.Editable = 'off';
            app.DelaySTextAreaLabel = uilabel(app.ConstantsPanel);
            app.DelaySTextAreaLabel.Text = 'Delay, S';
            app.MeasurementWindowLabel = uilabel(app.ConstantsPanel);
            app.MeasurementWindowLabel.Text = 'Measure Window, S';
            app.MeasureCountTextAreaLabel = uilabel(app.ConstantsPanel);
            app.MeasureCountTextAreaLabel.Text = 'Measure Count';
            app.DelaySTextArea = uitextarea(app.ConstantsPanel);
            app.DelaySTextArea.Editable = 'off';
            app.MeasureWindowSTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureWindowSTextArea.Editable = 'off';
            app.MeasureCountTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureCountTextArea.Editable = 'off';
            app.StartExperimentButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CapacitanceVoltageCVMFIAZurichTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CapacitanceVoltageCVMFIAZurichTab);
            app.StopExperimentButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CV_Zurich_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end